classdef app1_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        AxisAutoButton_2             matlab.ui.control.Button
        RejectFCButton               matlab.ui.control.Button
        AcceptFCButton               matlab.ui.control.Button
        MapquantityDropDown          matlab.ui.control.DropDown
        MapquantityDropDownLabel     matlab.ui.control.Label
        AxisAutoButton               matlab.ui.control.Button
        UpdateClimitsButton          matlab.ui.control.Button
        NewModulusMapButton          matlab.ui.control.Button
        UpperCLEditField             matlab.ui.control.NumericEditField
        UpperCLEditFieldLabel        matlab.ui.control.Label
        LowerCLEditField             matlab.ui.control.NumericEditField
        LowerCLEditFieldLabel        matlab.ui.control.Label
        IndentationDepthnmEditField  matlab.ui.control.NumericEditField
        IndentationDepthnmEditFieldLabel  matlab.ui.control.Label
        CurrentFileTextArea          matlab.ui.control.TextArea
        CurrentFileTextAreaLabel     matlab.ui.control.Label
        OverwriteCPButton            matlab.ui.control.Button
        SliderCP                     matlab.ui.control.Slider
        ColEditField                 matlab.ui.control.EditField
        ColEditFieldLabel            matlab.ui.control.Label
        RowEditField                 matlab.ui.control.EditField
        RowEditFieldLabel            matlab.ui.control.Label
        SliderRow                    matlab.ui.control.Slider
        Label                        matlab.ui.control.Label
        SliderCol                    matlab.ui.control.Slider
        SelectAFMdatamatfileButton   matlab.ui.control.Button
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes_4                     matlab.ui.control.UIAxes
        UIAxes_3                     matlab.ui.control.UIAxes
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end


    properties (Access = public)
        AFMdata % structure containing loaded AFM data (tip parameters)
        yl % yline
        xl % xline
        cpline %cp line
        cpline2 % cp line for derivative 
        fh % file handle of .mat file

        CurrentCP % current contact point from the CP slider
        CurrentD % current D vector from CP slider
        CurrentF % current F vector from CP slider
        CurrentPWE % current point wise modulus from CP slider
        CurrentE % current final modulus from CP slider

        movingMeanWindow % window for moving mean smoothing
        windowSize2 % window size for derivative


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectAFMdatamatfileButton
        function SelectAFMdatamatfileButtonPushed(app, event)
            [fh,p] = uigetfile();
            fh = [p fh];
            app.AFMdata = load(fh);
            app.fh = fh;
            app.movingMeanWindow = 10;
            app.windowSize2 = 10;


            value = app.MapquantityDropDown.Value;
            [nrows,ncols] = size(app.AFMdata.Height_Matrix);

            if isfield(app.AFMdata,'AcceptRejectMap') == 0
                %If this does not already exist, create a new one and set
                %it all true
                AcceptRejectMap = true(nrows,ncols);
                save(app.fh,'AcceptRejectMap','-append')
                app.AFMdata.AcceptRejectMap = AcceptRejectMap;
            end

            if strcmp(value,"Height")
                imagesc(app.UIAxes,app.AFMdata.Height_Matrix);
                title(app.UIAxes,'Relative Height (nm)')
                c = colorbar(app.UIAxes); c.Label.String = 'nm';
                set(app.UIAxes,'fontsize',12)
                set(app.UIAxes,'YDir','normal')
                axis(app.UIAxes, 'tight')
            elseif strcmp(value,"Modulus")
                imagesc(app.UIAxes,app.AFMdata.E_Matrix);
                title(app.UIAxes,'Elastic Modulus (kPa)')
                c = colorbar(app.UIAxes); c.Label.String = 'kPa';
                set(app.UIAxes,'fontsize',12)
                set(app.UIAxes,'YDir','normal')
                axis(app.UIAxes, 'tight')
            elseif strcmp(value,'Quality')
                imagesc(app.UIAxes,app.AFMdata.AcceptRejectMap);
                title(app.UIAxes,'Quality: Accept (1) Reject (0)')
                c = colorbar(app.UIAxes);
                set(app.UIAxes,'fontsize',12)
                set(app.UIAxes,'YDir','normal')
                axis(app.UIAxes, 'tight')
            else
                warning("This case shouldn't happen")
            end

            app.SliderRow.Limits = [1 nrows];
            app.SliderRow.MajorTicks = 1:nrows;
            app.SliderCol.Limits = [1 ncols];
            app.SliderCol.MajorTicks = 1:ncols;

            % Update second graph with raw curve and CP
            x = app.AFMdata.Ext_Matrix{round(app.SliderRow.Value),round(app.SliderCol.Value)};
            y = app.AFMdata.ExtDefl_Matrix{round(app.SliderRow.Value),round(app.SliderCol.Value)};
            yM = smooth(y,app.movingMeanWindow);
            dyM = diff(yM) ./ diff(x);
            dyM2 = smooth(dyM,app.windowSize2);
            title(app.UIAxes_2,'Raw Force Curve')
            xlabel(app.UIAxes_2,'Extension (nm)')
            ylabel(app.UIAxes_2,'Deflection(nm)')
            hold(app.UIAxes_2,'on')
            plot(app.UIAxes_2,x,y,'*')
            plot(app.UIAxes_2,x,yM,'k-')
            title(app.UIAxes2,'First Derivative')
            xlabel(app.UIAxes2,'Extension (nm)')
            hold(app.UIAxes2,'on')
            plot(app.UIAxes2,x(1:end-1),dyM2,'*-')


            thisCP = app.AFMdata.CP_Matrix(round(app.SliderRow.Value),round(app.SliderCol.Value));
            ndx_CP = find(x == thisCP);
            plot(app.UIAxes_2,x(ndx_CP),y(ndx_CP),'ko','markersize',10);
            plot(app.UIAxes2,x(ndx_CP),dyM(ndx_CP),'ko','markersize',10);
            legend(app.UIAxes_2,'Deflection','Smoothed','CP','location','best')
            % Limits will not work with single precision, convert to double
            if length(x)<0
                app.SliderCP.Limits = double([0 1]);
            else
                app.SliderCP.Limits = double([x(1) x(end)]);
            end

            if thisCP == 0 || isempty(ndx_CP) || ndx_CP < 1 || ndx_CP > length(x)
                app.SliderCP.Value = double(sum(app.SliderCP.Limits));
            else
                app.SliderCP.Value = double(x(ndx_CP));
            end


            D = app.AFMdata.D_Matrix{round(app.SliderRow.Value),round(app.SliderCol.Value)};
            E = app.AFMdata.PWE_Matrix{round(app.SliderRow.Value),round(app.SliderCol.Value)};
            plot(app.UIAxes_3,D(1:end),real(E(1:end)),'*')
            %semilogy(app.UIAxes_3,D(1:end),real(E(1:end)),'*')
            title(app.UIAxes_3,'Pointwise Modulus')
            ylim(app.UIAxes_3,[0 inf])
            ylabel(app.UIAxes_3,'E (kPa)')
            xlabel(app.UIAxes_3,'Depth (nm)')
            grid(app.UIAxes_3,'on')
            app.CurrentFileTextArea.Value = fh;

            delete(app.yl)
            app.yl = yline(app.UIAxes,app.SliderRow.Value);
            delete(app.xl)
            app.xl = xline(app.UIAxes,app.SliderCol.Value);


        end

        % Value changing function: SliderRow
        function SliderRowValueChanging(app, event)
            changingValue = round(event.Value);
            x = app.AFMdata.Ext_Matrix{changingValue,round(app.SliderCol.Value)};
            y = app.AFMdata.ExtDefl_Matrix{changingValue,round(app.SliderCol.Value)};
            yM = smooth(y,app.movingMeanWindow);
            dyM = diff(yM) ./ diff(x);
            dyM2 = smooth(dyM,app.windowSize2);
            hold(app.UIAxes_2,'off')
            plot(app.UIAxes_2,x,y,'*')
            hold(app.UIAxes_2,'on')
            plot(app.UIAxes_2,x,yM,'k-')
            thisCP = app.AFMdata.CP_Matrix(changingValue,round(app.SliderCol.Value));
            %ndx_CP = find(x == thisCP);
            err = abs(x-thisCP);
            ndx_CP = find(err == min(err));
            plot(app.UIAxes_2,x(ndx_CP),y(ndx_CP),'ko','markersize',10);
            legend(app.UIAxes_2,'Deflection','Smoothed','CP','location','best')
            title(app.UIAxes2,'First Derivative')
            xlabel(app.UIAxes2,'Extension (nm)')
            hold(app.UIAxes2,'off')
            plot(app.UIAxes2,x(1:end-1),dyM2,'*-')
            hold(app.UIAxes2,'on')
            plot(app.UIAxes2,x(ndx_CP),dyM(ndx_CP),'ko','markersize',10);


            if length(x)<0 || isempty(x)
                app.SliderCP.Limits = double([0 1]);
            else
                app.SliderCP.Limits = double([x(1) x(end)]);
            end

            if thisCP == 0 || isempty(ndx_CP) || ndx_CP < 1 || ndx_CP > length(x)
                app.SliderCP.Value = double(sum(app.SliderCP.Limits));
            else
                app.SliderCP.Value = double(x(ndx_CP));
            end

            D = app.AFMdata.D_Matrix{changingValue,round(app.SliderCol.Value)};
            E = app.AFMdata.PWE_Matrix{changingValue,round(app.SliderCol.Value)};
            plot(app.UIAxes_3,D(1:end),real(E(1:end)),'*')
            ylim(app.UIAxes_3,[0 inf])
            %semilogy(app.UIAxes_3,D(1:end),real(E(1:end)),'*')


            delete(app.yl)
            app.yl = yline(app.UIAxes,changingValue);

        end

        % Value changing function: SliderCol
        function SliderColValueChanging(app, event)
            changingValue = round(event.Value);
            x = app.AFMdata.Ext_Matrix{round(app.SliderRow.Value),changingValue};
            y = app.AFMdata.ExtDefl_Matrix{round(app.SliderRow.Value),changingValue};
            yM = smooth(y,app.movingMeanWindow);
            dyM = diff(yM) ./ diff(x);
            dyM2 = smooth(dyM, app.windowSize2);
            hold(app.UIAxes_2,'off')
            plot(app.UIAxes_2,x,y,'*')
            hold(app.UIAxes_2,'on')
            plot(app.UIAxes_2,x,yM,'k-')
            title(app.UIAxes2,'First Derivative')
            xlabel(app.UIAxes2,'Extension (nm)')
            hold(app.UIAxes2,'off')
            plot(app.UIAxes2,x(1:end-1),dyM2,'*-')
            hold(app.UIAxes2,'on')

            thisCP = app.AFMdata.CP_Matrix(round(app.SliderRow.Value),changingValue);
            %ndx_CP = find(x == thisCP);
            err = abs(x-thisCP);
            ndx_CP = find(err == min(err))
            plot(app.UIAxes_2,x(ndx_CP),y(ndx_CP),'ko','markersize',10);
            plot(app.UIAxes2,x(ndx_CP),dyM2(ndx_CP),'ko','markersize',10);
            legend(app.UIAxes_2,'Deflection','Smoothed','CP','location','best')

            if length(x)<0 || isempty(x)
                app.SliderCP.Limits = double([0 1]);
            else
                app.SliderCP.Limits = double([x(1) x(end)]);
            end

            if thisCP == 0 || isempty(ndx_CP) || ndx_CP < 1 || ndx_CP > length(x)
                app.SliderCP.Value = double(sum(app.SliderCP.Limits));
            else
                app.SliderCP.Value = double(x(ndx_CP));
            end

            D = app.AFMdata.D_Matrix{round(app.SliderRow.Value),changingValue};
            E = app.AFMdata.PWE_Matrix{round(app.SliderRow.Value),changingValue};
            % semilkogy(app.UIAxes_3,D(1:end),real(E(1:end)),'*')
            plot(app.UIAxes_3,D(1:end),real(E(1:end)),'*')
            ylim(app.UIAxes_3,[0 inf])
            app.ColEditField.Value = num2str(changingValue);
            delete(app.xl)
            app.xl = xline(app.UIAxes,changingValue);

        end

        % Value changing function: SliderCP
        function SliderCPValueChanging(app, event)
            changingValue = event.Value;
            x = app.AFMdata.Ext_Matrix{round(app.SliderRow.Value),round(app.SliderCol.Value)};
            y = app.AFMdata.ExtDefl_Matrix{round(app.SliderRow.Value),round(app.SliderCol.Value)};

            [~,CPndx] = min(abs(x - changingValue));


            delete(app.cpline)
            app.cpline = xline(app.UIAxes_2,changingValue);
            delete(app.cpline2)
            app.cpline2 = xline(app.UIAxes2,changingValue);
            app.CurrentCP = x(CPndx);

        end

        % Button pushed function: OverwriteCPButton
        function OverwriteCPButtonPushed(app, event)
            % First, update the PWE graph. Will need to be recalculated.
            changingValue = app.SliderCP.Value;
            x = app.AFMdata.Ext_Matrix{round(app.SliderRow.Value),round(app.SliderCol.Value)};
            y = app.AFMdata.ExtDefl_Matrix{round(app.SliderRow.Value),round(app.SliderCol.Value)};

            [~,CPndx] = min(abs(x - changingValue));
            h = y(CPndx:end) - y(CPndx); % h - h0
            z = x(CPndx:end) - x(CPndx); % z - z0
            D = z - h; % depth = z-zo - h-h0
            F = h.*app.AFMdata.spring_constant;
            R = app.AFMdata.R;
            th = app.AFMdata.th;
            b = app.AFMdata.b;
            [E_app,regimeChange] = calc_E_app(D,F,R,th,b,'pointwise',0);
            E_app = E_app * 1e18 * 1e-9; % Convert from nN / nM^2 to units of Pa
            E_app = E_app/1000; % units of kPa
            v = app.AFMdata.v;
            E = E_app .* 2 .* (1-v^2);

            %semilogy(app.UIAxes_3,D(1:end),real(E(1:end)),'*')
            plot(app.UIAxes_3,D(1:end),real(E(1:end)),'*')
            ylim(app.UIAxes_3,[0 inf])

            app.CurrentD = D;
            app.CurrentF = F;
            app.CurrentPWE = E;
            app.CurrentE = E(end);

            row = round(app.SliderRow.Value);
            col = round(app.SliderCol.Value);

            CP_Matrix = app.AFMdata.CP_Matrix;
            D_Matrix =  app.AFMdata.D_Matrix;
            F_Matrix = app.AFMdata.F_Matrix;
            PWE_Matrix = app.AFMdata.PWE_Matrix;
            E_Matrix = app.AFMdata.E_Matrix;


            CP_Matrix(row,col) = app.CurrentCP;
            D_Matrix{row,col} = app.CurrentD;
            F_Matrix{row,col} = app.CurrentF;
            PWE_Matrix{row,col} = app.CurrentPWE;
            E_Matrix(row,col) = app.CurrentE;
            Height_Matrix = max(CP_Matrix(:)) - CP_Matrix;

            updatedDate = date;
            updatedTime = clock;
            save(app.fh,'CP_Matrix','D_Matrix','F_Matrix',"PWE_Matrix" ...
                ,'E_Matrix','Height_Matrix' ...
                , 'updatedDate','updatedTime','-append');

            app.AFMdata = load(app.fh);
            value = app.MapquantityDropDown.Value;
            if strcmp(value,"Height")
                imagesc(app.UIAxes,app.AFMdata.Height_Matrix);
                title(app.UIAxes,'Relative Height (nm)')
                c = colorbar(app.UIAxes); c.Label.String = 'nm';
                set(app.UIAxes,'fontsize',12)
                set(app.UIAxes,'YDir','normal')
                axis(app.UIAxes, 'tight')
            elseif strcmp(value,"Modulus")
                imagesc(app.UIAxes,app.AFMdata.E_Matrix);
                title(app.UIAxes,'Elastic Modulus (kPa)')
                c = colorbar(app.UIAxes); c.Label.String = 'kPa';
                set(app.UIAxes,'fontsize',12)
                set(app.UIAxes,'YDir','normal')
                axis(app.UIAxes, 'tight')
            elseif strcmp(value,'Quality')
                imagesc(app.UIAxes,app.AFMdata.AcceptRejectMap);
                title('Quality: Accept (1) Reject (0)')
                c = colorbar(app.UIAxes);
                set(app.UIAxes,'fontsize',12)
                set(app.UIAxes,'YDir','normal')
                axis(app.UIAxes, 'tight')
            else

                warning("This case shouldn't happen")
            end
            delete(app.yl)
            app.yl = yline(app.UIAxes,round(app.SliderRow.Value));
            delete(app.xl)
            app.xl = xline(app.UIAxes,round(app.SliderCol.Value));


        end

        % Button pushed function: NewModulusMapButton
        function NewModulusMapButtonPushed(app, event)
            depthToUse = app.IndentationDepthnmEditField.Value;
            [nRows, nCols] = size(app.AFMdata.Height_Matrix);
            newModulusMap = zeros(nRows,nCols);

            for i_row = 1:nRows
                for i_col = 1:nCols
                    D = app.AFMdata.D_Matrix{i_row,i_col};
                    PWE = app.AFMdata.PWE_Matrix{i_row,i_col};
                    if isempty(D)
                        newModulusMap(i_row,i_col) = nan;
                    elseif D(end) < depthToUse
                        newModulusMap(i_row,i_col) = PWE(end);
                    else
                        [~,minIdx] = min(abs(D-depthToUse));
                        newModulusMap(i_row,i_col) = PWE(minIdx);
                    end
                end
            end
            imagesc(app.UIAxes_4,newModulusMap);
            titles = sprintf('Modulus (kPa) @ D = %i nm',depthToUse);
            title(app.UIAxes_4,titles)
            c = colorbar(app.UIAxes_4); c.Label.String = 'kPa';
            set(app.UIAxes_4,'fontsize',12)
            set(app.UIAxes_4,'YDir','normal')
            axis(app.UIAxes_4, 'tight')
            caxis(app.UIAxes_4,[app.LowerCLEditField.Value app.UpperCLEditField.Value]);


        end

        % Button pushed function: UpdateClimitsButton
        function UpdateClimitsButtonPushed(app, event)
            caxis(app.UIAxes_4,[app.LowerCLEditField.Value app.UpperCLEditField.Value]);
        end

        % Button pushed function: AxisAutoButton
        function AxisAutoButtonPushed(app, event)
            axis(app.UIAxes_2,'tight')
            axis(app.UIAxes2,'tight')
        end

        % Value changed function: MapquantityDropDown
        function MapquantityDropDownValueChanged(app, event)
            value = app.MapquantityDropDown.Value;
            if strcmp(value,"Height")
                imagesc(app.UIAxes,app.AFMdata.Height_Matrix);
                title(app.UIAxes,'Relative Height (nm)')
                c = colorbar(app.UIAxes); c.Label.String = 'nm';
                set(app.UIAxes,'fontsize',12)
                set(app.UIAxes,'YDir','normal')
                axis(app.UIAxes, 'tight')
            elseif strcmp(value,"Modulus")
                imagesc(app.UIAxes,app.AFMdata.E_Matrix);
                title(app.UIAxes,'Elastic Modulus (kPa)')
                c = colorbar(app.UIAxes); c.Label.String = 'kPa';
                set(app.UIAxes,'fontsize',12)
                set(app.UIAxes,'YDir','normal')
                axis(app.UIAxes, 'tight')
            elseif strcmp(value,'Quality')
                imagesc(app.UIAxes,app.AFMdata.AcceptRejectMap);
                title(app.UIAxes,'Quality: Accept (1) Reject (0)')
                c = colorbar(app.UIAxes);
                set(app.UIAxes,'fontsize',12)
                set(app.UIAxes,'YDir','normal')
                axis(app.UIAxes, 'tight')
            else
                warning("This case shouldn't happen")
            end
            delete(app.yl)
            app.yl = yline(app.UIAxes,round(app.SliderRow.Value));
            delete(app.xl)
            app.xl = xline(app.UIAxes,round(app.SliderCol.Value));
        end

        % Button pushed function: AcceptFCButton
        function AcceptFCButtonPushed(app, event)
            row = round(app.SliderRow.Value);
            col = round(app.SliderCol.Value);
            app.AFMdata.AcceptRejectMap(row,col) = true;
            AcceptRejectMap = app.AFMdata.AcceptRejectMap;
            save(app.fh,'AcceptRejectMap','-append')
            value = app.MapquantityDropDown.Value;
            if strcmp(value,'Quality')
                imagesc(app.UIAxes,app.AFMdata.AcceptRejectMap);
                title(app.UIAxes,'Quality: Accept (1) Reject (0)')
                c = colorbar(app.UIAxes);
                set(app.UIAxes,'fontsize',12)
                set(app.UIAxes,'YDir','normal')
                axis(app.UIAxes, 'tight')
            end

        end

        % Button pushed function: RejectFCButton
        function RejectFCButtonPushed(app, event)
            row = round(app.SliderRow.Value);
            col = round(app.SliderCol.Value);
            app.AFMdata.AcceptRejectMap(row,col) = false;
            AcceptRejectMap = app.AFMdata.AcceptRejectMap;
            save(app.fh,'AcceptRejectMap','-append')
            value = app.MapquantityDropDown.Value;
            if strcmp(value,'Quality')
                imagesc(app.UIAxes,app.AFMdata.AcceptRejectMap);
                title(app.UIAxes,'Quality: Accept (1) Reject (0)')
                c = colorbar(app.UIAxes);
                set(app.UIAxes,'fontsize',12)
                set(app.UIAxes,'YDir','normal')
                axis(app.UIAxes, 'tight')
            end

        end

        % Button pushed function: AxisAutoButton_2
        function AxisAutoButton_2Pushed(app, event)
            axis(app.UIAxes_3,'tight')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Title')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.SelectAFMdatamatfileButton = uibutton(app.UIFigure, 'push');
            app.SelectAFMdatamatfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAFMdatamatfileButtonPushed, true);
            app.SelectAFMdatamatfileButton.Text = 'Select AFM data (.mat file)';
            app.SliderCol = uislider(app.UIFigure);
            app.SliderCol.ValueChangingFcn = createCallbackFcn(app, @SliderColValueChanging, true);
            app.SliderCol.Value = 1;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {''; ''};
            app.SliderRow = uislider(app.UIFigure);
            app.SliderRow.ValueChangingFcn = createCallbackFcn(app, @SliderRowValueChanging, true);
            app.SliderRow.Value = 1;
            app.RowEditFieldLabel = uilabel(app.UIFigure);
            app.RowEditFieldLabel.Text = 'Row';
            app.RowEditField = uieditfield(app.UIFigure, 'text');
            app.RowEditField.Value = '1';
            app.ColEditFieldLabel = uilabel(app.UIFigure);
            app.ColEditFieldLabel.Text = 'Col';
            app.ColEditField = uieditfield(app.UIFigure, 'text');
            app.ColEditField.Value = '1';
            app.SliderCP = uislider(app.UIFigure);
            app.SliderCP.Limits = [-10.4 100];
            app.SliderCP.ValueChangingFcn = createCallbackFcn(app, @SliderCPValueChanging, true);
            app.OverwriteCPButton = uibutton(app.UIFigure, 'push');
            app.OverwriteCPButton.ButtonPushedFcn = createCallbackFcn(app, @OverwriteCPButtonPushed, true);
            app.OverwriteCPButton.Text = {'Overwrite CP'; ''};
            app.CurrentFileTextAreaLabel = uilabel(app.UIFigure);
            app.CurrentFileTextAreaLabel.Text = 'Current File';
            app.CurrentFileTextArea = uitextarea(app.UIFigure);
            app.IndentationDepthnmEditFieldLabel = uilabel(app.UIFigure);
            app.IndentationDepthnmEditFieldLabel.Text = 'Indentation Depth (nm)';
            app.IndentationDepthnmEditField = uieditfield(app.UIFigure, 'numeric');
            app.IndentationDepthnmEditField.Value = 1000;
            app.LowerCLEditFieldLabel = uilabel(app.UIFigure);
            app.LowerCLEditFieldLabel.Text = 'Lower CL ';
            app.LowerCLEditField = uieditfield(app.UIFigure, 'numeric');
            app.LowerCLEditField.Value = 3;
            app.UpperCLEditFieldLabel = uilabel(app.UIFigure);
            app.UpperCLEditFieldLabel.Text = 'Upper CL';
            app.UpperCLEditField = uieditfield(app.UIFigure, 'numeric');
            app.UpperCLEditField.Value = 30;
            app.NewModulusMapButton = uibutton(app.UIFigure, 'push');
            app.NewModulusMapButton.ButtonPushedFcn = createCallbackFcn(app, @NewModulusMapButtonPushed, true);
            app.NewModulusMapButton.Text = 'New Modulus Map';
            app.UpdateClimitsButton = uibutton(app.UIFigure, 'push');
            app.UpdateClimitsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateClimitsButtonPushed, true);
            app.UpdateClimitsButton.Text = 'Update Climits';
            app.AxisAutoButton = uibutton(app.UIFigure, 'push');
            app.AxisAutoButton.ButtonPushedFcn = createCallbackFcn(app, @AxisAutoButtonPushed, true);
            app.AxisAutoButton.Text = {'Axis Auto'; ''};
            app.MapquantityDropDownLabel = uilabel(app.UIFigure);
            app.MapquantityDropDownLabel.Text = 'Map quantity';
            app.MapquantityDropDown = uidropdown(app.UIFigure);
            app.MapquantityDropDown.Items = {'Height', 'Modulus', 'Quality', ''};
            app.MapquantityDropDown.ValueChangedFcn = createCallbackFcn(app, @MapquantityDropDownValueChanged, true);
            app.MapquantityDropDown.Value = 'Height';
            app.AcceptFCButton = uibutton(app.UIFigure, 'push');
            app.AcceptFCButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptFCButtonPushed, true);
            app.AcceptFCButton.Text = 'Accept FC';
            app.RejectFCButton = uibutton(app.UIFigure, 'push');
            app.RejectFCButton.ButtonPushedFcn = createCallbackFcn(app, @RejectFCButtonPushed, true);
            app.RejectFCButton.Text = 'Reject FC';
            app.AxisAutoButton_2 = uibutton(app.UIFigure, 'push');
            app.AxisAutoButton_2.ButtonPushedFcn = createCallbackFcn(app, @AxisAutoButton_2Pushed, true);
            app.AxisAutoButton_2.Text = {'Axis Auto'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end